classdef Covid_19_Data_Visualization < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Covid19DataVisualizationUIFigure  matlab.ui.Figure
        Panel                      matlab.ui.container.Panel
        OptionButtonGroup          matlab.ui.container.ButtonGroup
        CumulativeButton           matlab.ui.control.RadioButton
        DailyButton                matlab.ui.control.RadioButton
        DatatoPlotButtonGroup      matlab.ui.container.ButtonGroup
        CasesButton                matlab.ui.control.RadioButton
        DeathsButton               matlab.ui.control.RadioButton
        BothButton                 matlab.ui.control.RadioButton
        CountriesListBoxLabel      matlab.ui.control.Label
        CountriesListBox           matlab.ui.control.ListBox
        AveragedofdaysSliderLabel  matlab.ui.control.Label
        AveragedofdaysSlider       matlab.ui.control.Slider
        StateRegionListBoxLabel    matlab.ui.control.Label
        StateRegionListBox         matlab.ui.control.ListBox
        SearchEditFieldLabel       matlab.ui.control.Label
        SearchEditField            matlab.ui.control.EditField
        SearchEditField_2Label     matlab.ui.control.Label
        SearchEditField_2          matlab.ui.control.EditField
        UIAxes                     matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Globe % to store the pointer to object of class "globe"
        Country  %object of country
        State  %object of state
        
        %currently selected parameters
        slider_value  %for value in slider
        country_listvalue   %for current country name   (it also inlcude "Global")
        state_listvalue  %for current state name ("All" included)
        
        %radio button  "option"
        cumulative_button
        daily_button
        
        %radio button "data to plot"
        cases_button
        deaths_button
        both_button
        
        
        %use these 4 variables for plot
        cumu_cases
        cumu_deaths
        daily_case
        daily_death
        
        %label for the plot
        label
    end
    
    methods (Access = private)
        
        %reset 4 variables which will be needed for plot in accordance with
        %current selection of country and state
        function reset_cases_death(app)
            if app.state_listvalue ~= "All"
                app.cumu_cases = app.State.cumulative_cases;
                app.cumu_deaths = app.State.cumulative_deaths;
                app.daily_case = app.State.daily_cases;
                app.daily_death = app.State.daily_deaths;
                
            elseif app.country_listvalue ~= "Global"
                app.cumu_cases = app.Country.cumulative_cases;
                app.cumu_deaths = app.Country.cumulative_deaths;
                app.daily_case = app.Country.daily_cases;
                app.daily_death = app.Country.daily_deaths;
            else
                app.cumu_cases = app.Globe.cumulative_cases;
                app.cumu_deaths = app.Globe.cumulative_deaths;
                app.daily_case = app.Globe.daily_cases;
                app.daily_death = app.Globe.daily_deaths;
            end
            
        end
        
        
        %it average the daily data a/q to slider value
        function average_daily_data(app)
            dcases = movmean(app.daily_case,[app.slider_value-1,0]);
            ddeaths = movmean(app.daily_death,[app.slider_value-1,0]);
            app.daily_case = dcases(app.slider_value:end);
            app.daily_death = ddeaths(app.slider_value:end);
        end
        
        %create the title for the plot according to various selected options
        function label_creater(app)
            app.label = "";
            
            if app.cumulative_button
                app.label = sprintf("Cumulative Number of ");
            elseif app.daily_button
                app.label = sprintf("Daily Number of ");
            end
            
            if app.cases_button
                app.label = app.label + sprintf("Cases in the ");
            elseif app.deaths_button
                app.label = app.label + sprintf("Deaths in the ");
            elseif app.both_button
                app.label  =app.label + sprintf("Cases & Deaths in the ");
            end
            
            if app.country_listvalue == "Global"
                app.label = app.label + sprintf("World ");
            elseif app.state_listvalue == "All"
                app.label = app.label + sprintf("%s ",app.country_listvalue);
            else
                app.label = app.label + sprintf("%s (Country : %s) ",app.state_listvalue,app.State.country_of_state);
            end
            
            if app.slider_value ~= 1 && app.daily_button
                app.label = app.label + sprintf(" (%d-Day mean) ",app.slider_value);
            end
            
        end


        %plot the data
        function plot_basics(app)
            cla(app.UIAxes,"reset")
            daily_window = app.slider_value:length(app.cumu_cases);
            app.label_creater;
            
            if app.cases_button
                
                if app.cumulative_button
                    bar(app.UIAxes,app.cumu_cases,'EdgeColor',[0.3010 0.7450 0.9330]);
                    hold(app.UIAxes,"on")
                elseif app.daily_button
                    bar(app.UIAxes,daily_window,app.daily_case,'EdgeColor',[0.3010 0.7450 0.9330]);
                    hold(app.UIAxes,"on")
                end
                
                legend(app.UIAxes,"Cases","Location","northwest")
                hold(app.UIAxes,"on")
                
                
            elseif app.deaths_button
                if app.cumulative_button
                    plot(app.UIAxes,app.cumu_deaths,"Color",[1 0 0])
                    hold(app.UIAxes,"on")
                elseif app.daily_button
                    plot(app.UIAxes,daily_window,app.daily_death,"Color",[1 0 0])
                    hold(app.UIAxes,"on")
                end
                
                legend(app.UIAxes,"Deaths","Location","northwest")
                hold(app.UIAxes,"on")
                
                
            elseif app.both_button
                if app.cumulative_button
                    yyaxis(app.UIAxes,"left")
                    bar(app.UIAxes,app.cumu_cases,'EdgeColor',[0.3010 0.7450 0.9330])
                    hold(app.UIAxes,"on")
                    
                    yyaxis(app.UIAxes,"right")
                    plot(app.UIAxes,app.cumu_deaths,"Color",[1 0 0])
                    hold(app.UIAxes,"on")
                elseif app.daily_button
                    yyaxis(app.UIAxes,"left")
                    bar(app.UIAxes,daily_window,app.daily_case,'EdgeColor',[0.3010 0.7450 0.9330])
                    hold(app.UIAxes,"on")
                    
                    yyaxis(app.UIAxes,"right")
                    plot(app.UIAxes,daily_window,app.daily_death,"Color",[1 0 0])
                    hold(app.UIAxes,"on")
                end
                
                legend(app.UIAxes,"Cases","Deaths","Location","northwest")
                hold(app.UIAxes,"on")
            end
            app.UIAxes.YAxis(1).Exponent = 0;
            app.UIAxes.YAxis(1).TickLabelFormat = '%d';
            hold(app.UIAxes,'on')
            if app.both_button
                app.UIAxes.YAxis(2).Exponent = 0;
                app.UIAxes.YAxis(2).TickLabelFormat = '%d';
                hold(app.UIAxes,'on')
            end
            title(app.UIAxes,app.label,'FontSize',18,'FontName','Times New Roman');
            datetick(app.UIAxes,'x','mm/dd');
            hold(app.UIAxes,"off")
        end
        
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.Globe = globe(); %create the object of class "globe"
            
            %set initial parameters to default value
            app.country_listvalue = "Global";
            app.state_listvalue = "All";
            app.cases_button=1;
            app.deaths_button=0;
            app.both_button=0;
            app.cumulative_button=1;
            app.daily_button=0;
            app.reset_cases_death;  %function to obtain cases & deaths from object of class "globe,country,state"
            
            %update the country list box with help of object of class "globe" which is stored in property "Globe"
            app.CountriesListBox.Items = [app.CountriesListBox.Items,app.Globe.country_names];
            
            %call slider value function which call plot_basics function
            app.AveragedofdaysSliderValueChanged;
        end

        % Value changed function: CountriesListBox
        function CountriesListBoxValueChanged(app, event)
            app.country_listvalue = app.CountriesListBox.Value;
           
            app.StateRegionListBox.Items = "All";          
            app.state_listvalue = "All";
            if app.country_listvalue ~= "Global" %if we select any country
                app.Country = country(app.country_listvalue); %create the object of class "country"
                
                %update the state/region list box with state/regions of the country
                app.StateRegionListBox.Items = [app.StateRegionListBox.Items,app.Country.state_names];
            end
            
            app.AveragedofdaysSliderValueChanged;
        end

        % Value changed function: StateRegionListBox
        function StateRegionListBoxValueChanged(app, event)
            app.state_listvalue = app.StateRegionListBox.Value;
            
            if app.state_listvalue ~= "All" %if we select the state
                app.State = state(app.state_listvalue); %create the object of class "state"
            end
            
            app.AveragedofdaysSliderValueChanged;
        end

        % Value changed function: AveragedofdaysSlider
        function AveragedofdaysSliderValueChanged(app, event)
            % THIS IS MOST IMPORTANT FUNCTION/CALLBACK AS EVERY OTHER CALLBACK CALL IT TO PLOT THE GRAPH
            
            %force the slider to take only integers value
            app.slider_value = ceil(app.AveragedofdaysSlider.Value);
            app.AveragedofdaysSlider.Value = app.slider_value;
            
            %reset cases & deaths a/q to country/state
            app.reset_cases_death;
            
            %average the daily data a/q to slider value
            app.average_daily_data;
            
            app.plot_basics; %calling plotting function
        end

        % Selection changed function: DatatoPlotButtonGroup
        function DatatoPlotButtonGroupSelectionChanged(app, event)
            %when data to plot options is changed
            app.cases_button = app.CasesButton.Value;  %1 if case button selected 0 if not
            app.deaths_button =app.DeathsButton.Value;  %1 if death button selected 0 if not
            app.both_button = app.BothButton.Value;    %1 if both button selected 0 if not
            
            app.AveragedofdaysSliderValueChanged;
        end

        % Selection changed function: OptionButtonGroup
        function OptionButtonGroupSelectionChanged(app, event)
            %when options is changed
            app.cumulative_button =app.CumulativeButton.Value; %1 if cumulative button selected 0 if not
            app.daily_button = app.DailyButton.Value;  %1 if daily button selected 0 if not
            
            app.AveragedofdaysSliderValueChanged;
        end

        % Value changing function: SearchEditField
        function SearchEditFieldValueChanging(app, event)
            %Searching in Country List Box
            
            changingValue = event.Value;
            
            %check which country's name starts with word in seachfieldbox,  and ignore the case
            val = startsWith(app.CountriesListBox.Items,changingValue,'IgnoreCase',true);
            
            if any(val) %if any such country is found
                val = find(val,1); %selct the such first country's position
                
                %select that country in the box
                app.CountriesListBox.Value = string(app.CountriesListBox.Items{val});
                scroll(app.CountriesListBox,app.CountriesListBox.Value); %And scroll to that country
                
            else %if not found then set "Global" and scroll to top
                app.CountriesListBox.Value = "Global";
                scroll(app.CountriesListBox,'top');
                
            end
            %as country is changed, it will call the countrylistbox value changed function
            app.CountriesListBoxValueChanged;
        end

        % Value changing function: SearchEditField_2
        function SearchEditField_2ValueChanging(app, event)
            %Searching in State List Box
            changingValue = event.Value;
            
            %check which state or region's name starts with word in seachfieldbox,  and ignore the case
            val = startsWith(app.StateRegionListBox.Items,changingValue,'IgnoreCase',true);
            
            if any(val)  %if any such state is found
                val = find(val,1);  %selct the such first state's position
                
                %select that state in the box
                app.StateRegionListBox.Value = string(app.StateRegionListBox.Items{val});
                scroll(app.StateRegionListBox,app.StateRegionListBox.Value); %And scroll to that state
                
            else %if not found then set "All" and scroll to top
                app.StateRegionListBox.Value = "All";
                scroll(app.StateRegionListBox,'top');
            end
            %as state is changed, it will call the stateregionlistbox value changed function
            app.StateRegionListBoxValueChanged;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Covid19DataVisualizationUIFigure = uifigure('Visible', 'off');
            app.Covid19DataVisualizationUIFigure.Name = 'Covid-19 Data Visualization (Data Source : Johns Hopkins University)';
            app.Panel = uipanel(app.Covid19DataVisualizationUIFigure);
            app.OptionButtonGroup = uibuttongroup(app.Panel);
            app.OptionButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @OptionButtonGroupSelectionChanged, true);
            app.OptionButtonGroup.Title = 'Option';
            app.CumulativeButton = uiradiobutton(app.OptionButtonGroup);
            app.CumulativeButton.Text = 'Cumulative';
            app.CumulativeButton.Value = true;
            app.DailyButton = uiradiobutton(app.OptionButtonGroup);
            app.DailyButton.Text = 'Daily';
            app.DatatoPlotButtonGroup = uibuttongroup(app.Panel);
            app.DatatoPlotButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @DatatoPlotButtonGroupSelectionChanged, true);
            app.DatatoPlotButtonGroup.Title = 'Data to Plot';
            app.CasesButton = uiradiobutton(app.DatatoPlotButtonGroup);
            app.CasesButton.Text = 'Cases';
            app.CasesButton.Value = true;
            app.DeathsButton = uiradiobutton(app.DatatoPlotButtonGroup);
            app.DeathsButton.Text = 'Deaths';
            app.BothButton = uiradiobutton(app.DatatoPlotButtonGroup);
            app.BothButton.Text = 'Both';
            app.CountriesListBoxLabel = uilabel(app.Panel);
            app.CountriesListBoxLabel.Text = 'Countries';
            app.CountriesListBox = uilistbox(app.Panel);
            app.CountriesListBox.Items = {'Global'};
            app.CountriesListBox.ValueChangedFcn = createCallbackFcn(app, @CountriesListBoxValueChanged, true);
            app.CountriesListBox.Value = 'Global';
            app.AveragedofdaysSliderLabel = uilabel(app.Panel);
            app.AveragedofdaysSliderLabel.Text = 'Averaged # of days';
            app.AveragedofdaysSlider = uislider(app.Panel);
            app.AveragedofdaysSlider.Limits = [1 15];
            app.AveragedofdaysSlider.MajorTicks = [1 3 5 7 9 11 13 15];
            app.AveragedofdaysSlider.MajorTickLabels = {'1', '3', '5', '7', '9', '11', '13', '15'};
            app.AveragedofdaysSlider.ValueChangedFcn = createCallbackFcn(app, @AveragedofdaysSliderValueChanged, true);
            app.AveragedofdaysSlider.MinorTicks = [2 4 6 8 10 12 14];
            app.AveragedofdaysSlider.Value = 1;
            app.StateRegionListBoxLabel = uilabel(app.Panel);
            app.StateRegionListBoxLabel.Text = 'State/Region';
            app.StateRegionListBox = uilistbox(app.Panel);
            app.StateRegionListBox.Items = {'All'};
            app.StateRegionListBox.ValueChangedFcn = createCallbackFcn(app, @StateRegionListBoxValueChanged, true);
            app.StateRegionListBox.Value = 'All';
            app.SearchEditFieldLabel = uilabel(app.Panel);
            app.SearchEditFieldLabel.Text = 'Search';
            app.SearchEditField = uieditfield(app.Panel, 'text');
            app.SearchEditField.ValueChangingFcn = createCallbackFcn(app, @SearchEditFieldValueChanging, true);
            app.SearchEditField_2Label = uilabel(app.Panel);
            app.SearchEditField_2Label.Text = 'Search';
            app.SearchEditField_2 = uieditfield(app.Panel, 'text');
            app.SearchEditField_2.ValueChangingFcn = createCallbackFcn(app, @SearchEditField_2ValueChanging, true);
            app.UIAxes = uiaxes(app.Panel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            % Show the figure after all components are created
            app.Covid19DataVisualizationUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Covid_19_Data_Visualization

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Covid19DataVisualizationUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Covid19DataVisualizationUIFigure)
        end
    end
end